classdef InterfaceGrafica_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figIntGrafica   matlab.ui.Figure
        Untitled_1      matlab.ui.container.Menu
        ctrlPrint       matlab.ui.container.Menu
        ctrlMenu        matlab.ui.container.Menu
        ctrlSair        matlab.ui.container.Menu
        objGraph        matlab.ui.control.UIAxes
        uipanel1        matlab.ui.container.Panel
        edtFunc         matlab.ui.control.EditField
        txtFunction     matlab.ui.control.Label
        edtY0           matlab.ui.control.EditField
        edtA            matlab.ui.control.EditField
        text3           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        sliderH         matlab.ui.control.Slider
        edtB            matlab.ui.control.EditField
        text7           matlab.ui.control.Label
        edtN            matlab.ui.control.EditField
        uibuttongroup1  matlab.ui.container.ButtonGroup
        hlpEuler        matlab.ui.control.Button
        hlpEulerM       matlab.ui.control.Button
        hlpRK2          matlab.ui.control.Button
        hlpRK3          matlab.ui.control.Button
        hlpRK4          matlab.ui.control.Button
        hlpODE45        matlab.ui.control.Button
        hlpODE113       matlab.ui.control.Button
        hlpAdams        matlab.ui.control.Button
        cbEuler         matlab.ui.control.CheckBox
        cbEulerM        matlab.ui.control.CheckBox
        cbRK2           matlab.ui.control.CheckBox
        cbRK3           matlab.ui.control.CheckBox
        cbRK4           matlab.ui.control.CheckBox
        cbODE45         matlab.ui.control.CheckBox
        cbODE113        matlab.ui.control.CheckBox
        cbAdams         matlab.ui.control.CheckBox
        btnAtualizar    matlab.ui.control.Button
        btnReset        matlab.ui.control.Button
        objTable        matlab.ui.control.Table
        uipanel2        matlab.ui.container.Panel
        cbGuide         matlab.ui.control.CheckBox
        btnPrint        matlab.ui.control.Button
    end

    
    methods (Access = private)
        function uipushtool1_ClickedCallback(app, hObject, eventdata, handles)
            % hObject    handle to uipushtool1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            exportgraphics(handles.objGraph, 'Plot.png');
            a = msgbox("Ficheiro guardado na pasta com o nome 'Plot.png'", 'modal');
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.objTable.BackgroundColor = [1 1 1];
            app.objTable.ColumnFormat = {[] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InterfaceGrafica_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to InterfaceGrafica (see VARARGIN)
            
            % Choose default command line output for InterfaceGrafica
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: btnAtualizar
        function btnAtualizar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btnAtualizar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                strF = get(handles.edtFunc, 'String');
                f = @(t,y) eval(vectorize(strF));
                try
                    syms t y;
                    fTest = f(t,y);
                    set(handles.edtFunc, 'BackgroundColor', 'white');
                catch me
                    set(handles.edtFunc, 'BackgroundColor', 'red');
                    errordlg('ERRO! Introduza uma função em t e y', ...
                             'ERRO', 'modal');
                end
            
                a = str2num(get(handles.edtA, 'string'));
                if (isscalar(a) && isreal(a) && a < str2num(get(handles.edtB, 'String')))
                    set(handles.edtA, 'BackgroundColor', 'white');
                else
                    set(handles.edtA, 'BackgroundColor', 'red');
                    errordlg('Introduza um número real a, com a < b',...
                             'ERRO', 'modal');
                end
            
                b = str2num(get(handles.edtB, 'String'));
                if (isscalar(b) && isreal(b) && b > str2num(get(handles.edtA, 'String')))
                    set(handles.edtB, 'BackgroundColor', 'white');
                else
                    set(handles.edtB, 'BackgroundColor', 'red');
                    errordlg('Introduza um número real b, com b > a',...
                             'ERRO', 'modal');
                end
            
                y0 = str2num(get(handles.edtY0,'string'));
                ya = get(handles.edtA,'string');
                if (isscalar(y0) && isreal(y0))
                    set(handles.edtY0, 'BackgroundColor', 'white');
                else
                    set(handles.edtY0, 'BackgroundColor', 'red');
                    errordlg(['Introduza o valor de y(', ya,'), com y(', ya, ') != 0'],...
                             'ERRO', 'modal');
                end
            
                n = get(handles.sliderH, 'Value');
            
                % Calculo da solução particular
                solucao_particular = dsolve(['Dy = ', char(strF)],['y(', num2str(a), ') = ', num2str(y0)]);
                g = @(t)    eval(vectorize(char(solucao_particular)));
                h = (b - a) / n;
                t = zeros(1, n+1);
                t(1) = a;
                yExata = g(a);
                for i=1:n
                    t(i+1) = t(i) + h;
                    yExata = g(t);
                end
            
                table_data = [transpose(t), transpose(yExata)];
                set(handles.objTable, 'Data', table_data, 'ColumnName', {'T', 'Exata'});
                table_data = get(handles.objTable, 'Data');
                plot(handles.objGraph, t, yExata);
                plot(t, yExata, '-r*');
                legend('Exata');
                hold on
            
                % Fazer o plot dos outros métodos
                if (get(handles.cbEuler, 'Value') == 1)
                    yEuler = Euler(f, a, b, n, y0);
                    erroEuler = abs(yEuler - yExata);
                    table_data(:,end+1) = transpose(yEuler);
                    table_data(:,end+1) = transpose(erroEuler);
                    table_name = get(handles.objTable, 'ColumnName');
                    table_name{end+1} = 'Euler';
                    table_name{end+1} = 'Erro - Euler';
                    set(handles.objTable, 'Data', table_data);
                    set(handles.objTable, 'ColumnName', table_name);
                    plot(handles.objGraph, t, yEuler, '-bo', 'DisplayName', 'Euler');
                    legend('off');
                    legend('show');
                end
            
                if (get(handles.cbEulerM, 'Value') == 1)
                    yEulerM = EulerM(f, a, b, n, y0);
                    erroEulerM = abs(yEulerM - yExata);
                    table_data(:,end+1) = transpose(yEulerM);
                    table_data(:,end+1) = transpose(erroEulerM);
                    table_name = get(handles.objTable, 'ColumnName');
                    table_name{end+1} = 'Euler Melhorado';
                    table_name{end+1} = 'Erro - EulerM';
                    set(handles.objTable, 'Data', table_data);
                    set(handles.objTable, 'ColumnName', table_name);
                    plot(handles.objGraph, t, yEulerM, '-go', 'DisplayName', 'Euler Melhorado');
                    legend('off');
                    legend('show');
                end
            
                if (get(handles.cbRK2, 'Value') == 1)
                    yRK2 = RK2(f, a, b, n, y0);
                    erroRK2 = abs(yRK2 - yExata);
                    table_data(:,end+1) = transpose(yRK2);
                    table_data(:,end+1) = transpose(erroRK2);
                    table_name = get(handles.objTable, 'ColumnName');
                    table_name{end+1} = 'RK2';
                    table_name{end+1} = 'Erro - RK2';
                    set(handles.objTable, 'Data', table_data);
                    set(handles.objTable, 'ColumnName', table_name);
                    plot(handles.objGraph, t, yRK2, '-co', 'DisplayName', 'Runge-Kutta 2');
                    legend('off');
                    legend('show');
                end
            
                if (get(handles.cbRK3, 'Value') == 1)
                    yRK3 = RK3(f, a, b, n, y0);
                    erroRK3 = abs (yRK3 - yExata);
                    table_data(:,end+1) = transpose(yRK3);
                    table_data(:,end+1) = transpose(erroRK3);
                    table_name = get(handles.objTable, 'ColumnName');
                    table_name{end+1} = 'RK3';
                    table_name{end+1} = 'Erro - RK3';
                    set(handles.objTable, 'Data', table_data);
                    set(handles.objTable, 'ColumnName', table_name);
                    plot(handles.objGraph, t, yRK3, '-yo', 'DisplayName', 'Runge-Kutta 3');
                    legend('off');
                    legend('show');
                end
            
                if (get(handles.cbRK4, 'Value') == 1)
                    yRK4 = RK4(f, a, b, n, y0);
                    erroRK4 = abs (yRK4 - yExata);
                    table_data(:,end+1) = transpose(yRK4);
                    table_data(:,end+1) = transpose(erroRK4);
                    table_name = get(handles.objTable, 'ColumnName');
                    table_name{end+1} = 'RK4';
                    table_name{end+1} = 'Erro - RK4';
                    set(handles.objTable, 'Data', table_data);
                    set(handles.objTable, 'ColumnName', table_name);
                    plot(handles.objGraph, t, yRK4, '-mo', 'DisplayName', 'Runge-Kutta 4');
                    legend('off');
                    legend('show');
                end
            
                if (get(handles.cbODE45, 'Value') == 1)
                    [~,yODE45] = ODE45(f, a, b, n, y0);
                    yODE45 = yODE45.';
                    erroODE45 = abs (yODE45 - yExata);
                    table_data(:,end+1) = transpose(yODE45);
                    table_data(:,end+1) = transpose(erroODE45);
                    table_name = get(handles.objTable, 'ColumnName');
                    table_name{end+1} = 'ODE45';
                    table_name{end+1} = 'Erro - ODE45';
                    set(handles.objTable, 'Data', table_data);
                    set(handles.objTable, 'ColumnName', table_name);
                    plot(handles.objGraph, t, yODE45, '-ko', 'DisplayName', 'ODE45');
                    legend('off');
                    legend('show');
            
                end
            
                if (get(handles.cbODE113, 'Value') == 1)
                    [~,yODE113] = ODE113(f, a, b, n, y0);
                    yODE113 = yODE113.';
                    erroODE113 = abs (yODE113 - yExata);
                    table_data(:,end+1) = transpose(yODE113);
                    table_data(:,end+1) = transpose(erroODE113);
                    table_name = get(handles.objTable, 'ColumnName');
                    table_name{end+1} = 'ODE113';
                    table_name{end+1} = 'Erro - ODE113';
                    set(handles.objTable, 'Data', table_data);
                    set(handles.objTable, 'ColumnName', table_name);
                    plot(handles.objGraph, t, yODE113, '-k*', 'DisplayName', 'ODE113');
                    legend('off');
                    legend('show');
            
                end
            
                 if (get(handles.cbAdams, 'Value') == 1)
                    yAdams = Adams(f, a, b, n, y0);
                    erroAdams = abs (yAdams - yExata);
                    table_data(:,end+1) = transpose(yAdams);
                    table_data(:,end+1) = transpose(erroAdams);
                    table_name = get(handles.objTable, 'ColumnName');
                    table_name{end+1} = 'Adams';
                    table_name{end+1} = 'Erro - Adams';
                    set(handles.objTable, 'Data', table_data);
                    set(handles.objTable, 'ColumnName', table_name);
                    plot(handles.objGraph, t, yAdams, ':r*', 'DisplayName', 'Adams-Bashforth');
                    legend('off');
                    legend('show');
            
                end
            
                hold off
            
                if(get(handles.cbGuide, 'Value') == 1)
                    grid on;
                else
                    grid off;
                end
        end

        % Button pushed function: btnPrint
        function btnPrint_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btnPrint (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % print(handles.figIntGrafica, 'Interface Grafica', '-dpdf', 'PaperOrientation', 'landscape');
            exportgraphics(handles.objGraph, 'Plot.png');
            a = msgbox("Ficheiro guardado na pasta com o nome 'Plot.png'", 'modal');
        end

        % Button pushed function: btnReset
        function btnReset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btnReset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                cla(handles.objGraph);
                set(handles.edtA, 'String', '0');
                set(handles.edtB, 'String', '2');
                set(handles.edtY0, 'String', '1');
                set(handles.edtN, 'String', '10');
                set(handles.sliderH, 'Value', 10);
                set(handles.objTable, 'Data', {});
            
                set(handles.cbEuler, 'Value', 0);
                set(handles.cbEulerM, 'Value', 0);
                set(handles.cbRK2, 'Value', 0);
                set(handles.cbRK3, 'Value', 0);
                set(handles.cbRK4, 'Value', 0);
                set(handles.cbODE45, 'Value', 0);
                set(handles.cbODE113, 'Value', 0);
                set(handles.cbAdams, 'Value', 0);
            
                table_name = {'t', 'Exata'};
                set(handles.objTable, 'ColumnName', table_name);
        end

        % Value changed function: cbGuide
        function cbGuide_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cbGuide (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of cbGuide
            if(get(hObject, 'Value') == 1)
                grid on;
            else
                grid off;
            end
        end

        % Menu selected function: ctrlMenu
        function ctrlMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ctrlMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SN=questdlg('Quer mesmo sair?','Sair','Sim','Não','Sim');
            if strcmp(SN,'Não')
                return;
            end
            delete(handles.figIntGrafica)
            run menu_App.mlapp
        end

        % Menu selected function: ctrlPrint
        function ctrlPrint_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ctrlPrint (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            exportgraphics(handles.objGraph, 'Plot.png');
            a = msgbox("Ficheiro guardado na pasta com o nome 'Plot.png'", 'modal');
        end

        % Menu selected function: ctrlSair
        function ctrlSair_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ctrlSair (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SN=questdlg('Quer mesmo sair?','Sair','Sim','Não','Sim');
            if strcmp(SN,'Não')
                return;
            end
            delete(handles.figIntGrafica)
        end

        % Value changed function: edtA
        function edtA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtA (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edtA as text
            %        str2double(get(hObject,'String')) returns contents of edtA as a double
            
                a = str2num(get(hObject, 'string'));
                if (isscalar(a) && isreal(a) && a < str2num(get(handles.edtB, 'String')))
                    set(hObject, 'BackgroundColor', 'white');
                else
                    set(hObject, 'BackgroundColor', 'red');
                    errordlg('Introduza um número real a, com a < b',...
                             'ERRO', 'modal');
                end
            
                handles.a = a;
                guidata(hObject, handles);
        end

        % Value changed function: edtB
        function edtB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edtB as text
            %        str2double(get(hObject,'String')) returns contents of edtB as a double
            
                b = str2num(get(hObject, 'String'));
                if (isscalar(b) && isreal(b) && b > str2num(get(handles.edtA, 'String')))
                    set(hObject, 'BackgroundColor', 'white');
                else
                    set(hObject, 'BackgroundColor', 'red');
                    errordlg('Introduza um número real b, com b > a',...
                             'ERRO', 'modal');
                end
            
                handles.b = b;
                guidata(hObject, handles);
        end

        % Value changed function: edtFunc
        function edtFunc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtFunc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edtFunc as text
            %        str2double(get(hObject,'String')) returns contents of edtFunc as a double
                strF = get(hObject, 'String');
                f = @(t,y) eval(vectorize(strF));
                try
                    syms t y;
                    fTest = f(t,y);
                    set(hObject, 'BackgroundColor', 'white');
                catch me
                    set(hObject, 'BackgroundColor', 'red');
                    errordlg('ERRO! Introduza uma função em t e y', ...
                             'ERRO', 'modal');
                end
            
                handles.funcao = f;
                guidata(hObject, handles);
        end

        % Value changed function: edtN
        function edtN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edtN as text
            %        str2double(get(hObject,'String')) returns contents of edtN as a double
            
            val = str2double(get(hObject,'String'));
            
            % Determine whether val is a number between 0 and 1.
            if isnumeric(val) && length(val)==1 && ...
               val >= get(handles.sliderH,'Min') && ...
               val <= get(handles.sliderH,'Max')
                set(handles.sliderH,'Value',val);
                set(hObject, 'BackgroundColor', 'white');
            
            else
            % Increment the error count, and display it.
                    errordlg({'     Introduziu um valor de N inválido';...
                         'N deve estar compreendido entre 1 e 100'},...
                        'ERRO', 'modal');
                    set(hObject, 'BackgroundColor', 'red');
               % Restore focus to the edit text box after error
               uicontrol(hObject)
            end
        end

        % Value changed function: edtY0
        function edtY0_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtY0 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edtY0 as text
            %        str2double(get(hObject,'String')) returns contents of edtY0 as a double
                y0 = str2num(get(hObject,'string'));
                a = get(handles.edtA,'string');
                if (isscalar(y0) && isreal(y0))
                    set(hObject, 'BackgroundColor', 'white');
                else
                    set(hObject, 'BackgroundColor', 'red');
                    errordlg(['Introduza o valor de y(', a,'), com y(', a, ') != 0'],...
                             'ERRO', 'modal');
                end
            
                handles.y0 = y0;
                guidata(hObject, handles);
        end

        % Close request function: figIntGrafica
        function figIntGrafica_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figIntGrafica (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            SN=questdlg('Quer mesmo sair?','Sair','Sim','Não','Sim');
            if strcmp(SN,'Não')
                return;
            end
            delete(handles.figIntGrafica)
        end

        % Button pushed function: hlpAdams
        function hlpAdams_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hlpAdams (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                    MÉTODO DE ADAMS-BASHFORTH DE ORDEM 4';' ';...
                   'y  = Adams(f, a, b, n, y0) - Método numérico para a resolução de um PVI';...
                   "y' = f(t,y), com t = [a,b] e y(a) = y0 - Condição inicial";' ';...
            
               '-> INPUT:';"f      - função do 2º membro da Equação Diferencial: y' = f(t,y)";...
                  "[a, b] - extremos do intervalo da variável independente t";...
                  "n      - número de subintervalos ou interações do método";...
                  "y0     - condição inicial: t = a -> y = y0";' ';...
            
               '-> OUTPUT:';"yAdams - Vetor das soluções aproximadas da solução exata";...
                  "y(n+1) = y(n) + (55 * f(t(n), y(n)) - 59 * f(t(n-1), y(n-1)) + 37 * f(t(n-2), y(n-2)) - 9 * f(t(n-3), y(n-3))) * (h / 24);";' ';...
            
                  'O método de previsão e correção de Adams-Bashforth é um método de';...
                  'passo múltiplo que combina as fórmulas de Adams-Bashforth e Adams-Moulton';...
                  'de quarto grau. Ambas são de passo múltiplo, mas a diferença é que a primeira';...
                  'é uma fórmula explícita e a segunda implícita. Os métodos de passo múltiplo';...
                  'evitam as múltiplas etapas do método de Runge-Kutta, embora haja a necessidade';...
                  'de serem iniciados com suas condições iniciais.';' '; ' '},...
                  'Método de Adams-Bashforth de Ordem 4');
        end

        % Button pushed function: hlpEulerM
        function hlpEulerM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hlpEulerM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                                METODO DE EULER MELHORADO PARA PVI';' ';...
                   'y = MEulerM(f,a,b,n,y0) - Método numérico para a resolução de um PVI';...
                   "y'= f(t,y) com t=[a, b] e y(a)=y0 - Condição inicial";' ';...
            
                   '-> INPUT:';"f       - função do 2.º membro da Equação Diferencial: y' = f(t,y)";...
                   "[a, b]  - extremos do intervalo da variável independente t";...
                   "n       - número de subintervalos ou iterações do método";...
                   "y0      - condição inicial t = a -> y = y0";' ';...
            
                   '->  OUTPUT:';"y - vetor das soluções aproximadas da solução exata";...
                   "y(i+1) = y(i)+h/2 * f(t(i), y(i)) + f(t(i+1), y(i+1), i =0,1,...,n-1";' ';...
            
                   'O Método de Euler Melhorado, consiste em uma aproximação semelhante';...
                   'ao primeiro método utilizado, o Método de Euler, onde para fazer a aproximação devemos';...
                   'realizar todos os passos do método de Euler, desta forma observamos que o';...
                   'método de Euler melhorado é uma modificação do método de Euler.';' '; ' '},...
                   'Método de Euler Melhorado');
        end

        % Button pushed function: hlpEuler
        function hlpEuler_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hlpEuler (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                                       MÉTODO DE EULER PARA PVI';' ';...
                'yEuler = Euler(f, a, b, n, y0) - Método numérico para a resolução de um PVI';...
                "y'= f(t,y) com t=[a, b] e y(a)=y0 - Condição inicial"; ' ';...
            
                '   -> INPUT:';"f       - função do 2.º membro da Equação Diferencial: y' = f(t,y)";...
                "[a, b]  - extremos do intervalo da variável independente t";...
                "n       - número de subintervalos ou iterações do método";...
                "y0      - condição inicial t = a -> y = y0";' ';...
            
                '   -> OUTPUT: '; "y - vetor das soluções aproximadas da solução exata";...
                "y(i+1) = y(i)+h*f(t(i),y(i)) , i = 0, 1, ..., n-1";' ';...
            
                'De acordo com Boyce e Diprima os trabalhos iniciais em métodos';['numéricos',...
                'se iniciaram com Isaac Newton e Leibniz, mas foi com Lehonard'];['Euler',...
                'no sec. XVIII que os métodos numéricos tiveram forma mais concreta,'];['Euler',...
                'deduziu um processo iterativo que permitia determinar, de forma'];['aproximada,',...
                'a solução de um problema de condição inicial num determinado'];'ponto. ';['É o tipo',...
                'mais básico de método explícito para integração numérica '];'para equações diferenciais ordinárias.';' '; ' '},...
                'Método de Euler');
        end

        % Button pushed function: hlpODE113
        function hlpODE113_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hlpODE113 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                                       ODE 113 do Matlab (ODE113)';' ';...
                  'y  = ODE45(f, a, b, n, y0) - Função do Matlab para a resolução de um PVI';...
                  "y' = f(t,y), com t = [a,b] e y(a) = y0 - Condição inicial";' ';...
            
               '-> INPUT:';"f      - função do 2º membro da Equação Diferencial: y' = f(t,y)";...
                  "[a, b] - extremos do intervalo da variável independente t";...
                  "n      - número de subintervalos ou interações do método";...
                  "y0     - condição inicial: t = a -> y = y0";' ';...
            
               '-> OUTPUT:';"t      - discretização do domínio";...
                  "y      - vector das aproximações discretas da solução exacta";' ';...
            
               'A ODE113 é uma função do Matlab para resolver sistemas de equações';...
               "do tipo y' = f(t,y), ou problemas que envolvem matrizes de massa,";...
               "M(t,y)y'=f(t,y). Esta função implementa um método de resolução";...
               'multipasso, muito mais preciso que qualquer outro método abordado,';...
               'porém menos utilizado devido à sua complexidade de cálculo.';' '; ' '},...
               'Função ODE113 do Matlab');
        end

        % Button pushed function: hlpODE45
        function hlpODE45_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hlpODE45 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                                       ODE 45 do Matlab (ODE45)';' ';...
                  'y  = ODE45(f, a, b, n, y0) - Função do Matlab para a resolução de um PVI';...
                  "y' = f(t,y), com t = [a,b] e y(a) = y0 - Condição inicial";' ';...
            
               '-> INPUT:';"f      - função do 2º membro da Equação Diferencial: y' = f(t,y)";...
                  "[a, b] - extremos do intervalo da variável independente t";...
                  "n      - número de subintervalos ou interações do método";...
                  "y0     - condição inicial: t = a -> y = y0";' ';...
            
               '-> OUTPUT:';"t      - discretização do domínio";...
                  "y      - vector das aproximações discretas da solução exacta";' ';...
            
               'A ODE45 é uma função do Matlab para resolver sistemas de equações';...
               "do tipo y' = f(t,y), ou problemas que envolvem matrizes de massa,";...
               "M(t,y)y'=f(t,y). Esta função implementa um método de Runge-Kutta,";...
               'através de iterações sucessivas computacionalmente.';' '; ' '},...
               'Função ODE45 do Matlab');
        end

        % Button pushed function: hlpRK2
        function hlpRK2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hlpRK2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                    MÉTODO DE RUNGE - KUTTA DE ORDEM 2 (RK2)';' ';...
                  'y  = RK2(f, a, b, n, y0) - Método numérico para a resolução de um PVI';...
                  "y' = f(t,y), com t = [a,b] e y(a) = y0 - Condição inicial";' ';...
            
               '-> INPUT:';"f      - função do 2º membro da Equação Diferencial: y' = f(t,y)";...
                  "[a, b] - extremos do intervalo da variável independente t";...
                  "n      - número de subintervalos ou interações do método";...
                  "y0     - condição inicial: t = a -> y = y0";' ';...
            
               '-> OUTPUT:';"y - vetor das soluções aproximadas da solução exata";...
                  "y(i+1) = y(i) + 1/2 * (k1 * k2), i = 0, 1, ..., n-1, com k1, k2:";...
                      "k1     = h * f(t(i), y(i)),";...
                      "k2     = h * f(t(i+1), y(i+1)).";' ';...
            
               'O método de Runge - Kutta de ordem 2 (RK2) é um dos métodos númericos';...
               'desenvolvidos pelos matemáticos alemães Carl Runge e Martin Kutta, por';...
               'volta de 1900. Os métodos desenvolvidos pelos dois matemáticos formam';...
               'uma vasta família de métodos numéricos diretos de passo simples para a';...
               'resolução de Equações Diferenciais Ordinárias / Problemas de Valor Inicial.';' '; ' '},...
               'Método de Runge-Kutta de Ordem 2');
        end

        % Button pushed function: hlpRK3
        function hlpRK3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hlpRK3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                    MÉTODO DE RUNGE - KUTTA DE ORDEM 3 (RK3)';' ';...
                  'y  = RK3(f, a, b, n, y0) - Método numérico para a resolução de um PVI';...
                  "y' = f(t,y), com t = [a,b] e y(a) = y0 - Condição inicial";' ';...
            
               '-> INPUT:';"f      - função do 2º membro da Equação Diferencial: y' = f(t,y)";...
                  "[a, b] - extremos do intervalo da variável independente t";...
                  "n      - número de subintervalos ou interações do método";...
                  "y0     - condição inicial: t = a -> y = y0";' ';...
            
               '-> OUTPUT:';"y - vetor das soluções aproximadas da solução exata";...
                  "y(i+1) = y(i) + 1/2 * (k1 * k2), i = 0, 1, ..., n-1, com k1, k2:";...
                      "k1     = h * f(t(i), y(i)),";...
                      "k2     = h * f(t(i+1), y(i+1)),";...
                      "K3     = h * f(t(i)+(h/2), y(i)+(K2/2)).;";' ';...
            
               'O método de Runge - Kutta de ordem 3 (RK3) é um dos métodos númericos';...
               'desenvolvidos pelos matemáticos alemães Carl Runge e Martin Kutta, por';...
               'volta de 1900. Os métodos desenvolvidos pelos dois matemáticos formam';...
               'uma vasta família de métodos numéricos diretos de passo simples para a';...
               'resolução de Equações Diferenciais Ordinárias / Problemas de Valor Inicial.';' '; ' '},...
               'Método de Runge-Kutta de Ordem 3');
        end

        % Button pushed function: hlpRK4
        function hlpRK4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hlpRK4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                    MÉTODO DE RUNGE - KUTTA DE ORDEM 4 (RK4)';' ';...
                  'y  = RK4(f, a, b, n, y0) - Método numérico para a resolução de um PVI';...
                  "y' = f(t,y), com t = [a,b] e y(a) = y0 - Condição inicial";' ';...
            
               '-> INPUT:';"f      - função do 2º membro da Equação Diferencial: y' = f(t,y)";...
                  "[a, b] - extremos do intervalo da variável independente t";...
                  "n      - número de subintervalos ou interações do método";...
                  "y0     - condição inicial: t = a -> y = y0";' ';...
            
               '-> OUTPUT:';"y - vetor das soluções aproximadas da solução exata";...
                  "y(i+1) = y(i) + 1/2 * (k1 * k2), i = 0, 1, ..., n-1, com k1, k2, k3, k4:";...
                       "K1 = h * f(t(i), yRK4(i));";...
                       "K2 = h * f(t(i)+(h/2), yRK4(i)+(K1)/2);";...
                       "K3 = h * f(t(i)+(h/2),yRK4(i)+(K2/2));";...
                       "K4 = h * f(t(i+1), yRK4(i)+K3).";' ';...
            
               'O método de Runge-Kutta de ordem 4 (RK4) é mais um dos muitos métodos numéricos';...
               'iterativos, implícitos e explícitos, para a resolução numérica (aproximação) de';...
               'soluções de Equações Diferenciais Ordinárias / Problemas de Valor Inicial.';...
               'O RK4 é considerado o método mais preciso da família Runge - Kutta, sendo que';...
               'quanto maior for o número de subintervalos, maior será a aproximação à solução';...
               'exata.';' '; ' '},...
               'Método de Runge-Kutta de Ordem 4');
        end

        % Value changed function: sliderH
        function sliderH_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderH (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            val = round(hObject.Value);
            hObject.Value = val;
            set(handles.edtN, 'String', num2str(get(hObject, 'Value')));
            handles.N = val;
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figIntGrafica = uifigure('Visible', 'off');
            app.figIntGrafica.Name = 'InterfaceGrafica';
            app.figIntGrafica.CloseRequestFcn = createCallbackFcn(app, @figIntGrafica_CloseRequestFcn, true);
            app.figIntGrafica.Tag = 'figIntGrafica';
            app.Untitled_1 = uimenu(app.figIntGrafica);
            app.Untitled_1.Text = 'Menu';
            app.Untitled_1.Tag = 'Untitled_1';
            app.ctrlPrint = uimenu(app.Untitled_1);
            app.ctrlPrint.MenuSelectedFcn = createCallbackFcn(app, @ctrlPrint_Callback, true);
            app.ctrlPrint.Accelerator = 'S';
            app.ctrlPrint.Text = 'Guardar Gráfico';
            app.ctrlPrint.Tag = 'ctrlPrint';
            app.ctrlMenu = uimenu(app.Untitled_1);
            app.ctrlMenu.MenuSelectedFcn = createCallbackFcn(app, @ctrlMenu_Callback, true);
            app.ctrlMenu.Text = 'Voltar';
            app.ctrlMenu.Tag = 'ctrlMenu';
            app.ctrlSair = uimenu(app.Untitled_1);
            app.ctrlSair.MenuSelectedFcn = createCallbackFcn(app, @ctrlSair_Callback, true);
            app.ctrlSair.Text = 'Sair';
            app.ctrlSair.Tag = 'ctrlSair';
            app.objGraph = uiaxes(app.figIntGrafica);
            app.objGraph.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.objGraph.Tag = 'objGraph';
            app.uipanel1 = uipanel(app.figIntGrafica);
            app.uipanel1.Title = 'Dados do PVI';
            app.uipanel1.Tag = 'uipanel1';
            app.edtFunc = uieditfield(app.uipanel1, 'text');
            app.edtFunc.ValueChangedFcn = createCallbackFcn(app, @edtFunc_Callback, true);
            app.edtFunc.Tag = 'edtFunc';
            app.edtFunc.Value = 'y';
            app.txtFunction = uilabel(app.uipanel1);
            app.txtFunction.Tag = 'txtFunction';
            app.txtFunction.Text = 'FUNÇÃO';
            app.edtY0 = uieditfield(app.uipanel1, 'text');
            app.edtY0.ValueChangedFcn = createCallbackFcn(app, @edtY0_Callback, true);
            app.edtY0.Tag = 'edtY0';
            app.edtY0.Value = '1';
            app.edtA = uieditfield(app.uipanel1, 'text');
            app.edtA.ValueChangedFcn = createCallbackFcn(app, @edtA_Callback, true);
            app.edtA.Tag = 'edtA';
            app.edtA.Value = '0';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'a';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'b';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'y0';
            app.sliderH = uislider(app.uipanel1);
            app.sliderH.Limits = [1 100];
            app.sliderH.MajorTicks = [];
            app.sliderH.ValueChangedFcn = createCallbackFcn(app, @sliderH_Callback, true);
            app.sliderH.MinorTicks = [];
            app.sliderH.Tag = 'sliderH';
            app.sliderH.Value = 10;
            app.edtB = uieditfield(app.uipanel1, 'text');
            app.edtB.ValueChangedFcn = createCallbackFcn(app, @edtB_Callback, true);
            app.edtB.Tag = 'edtB';
            app.edtB.Value = '2';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'N';
            app.edtN = uieditfield(app.uipanel1, 'text');
            app.edtN.ValueChangedFcn = createCallbackFcn(app, @edtN_Callback, true);
            app.edtN.Tag = 'edtN';
            app.edtN.Value = '10';
            app.uibuttongroup1 = uibuttongroup(app.figIntGrafica);
            app.uibuttongroup1.Title = 'Método a utilizar';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.hlpEuler = uibutton(app.uibuttongroup1, 'push');
            app.hlpEuler.ButtonPushedFcn = createCallbackFcn(app, @hlpEuler_Callback, true);
            app.hlpEuler.Tag = 'hlpEuler';
            app.hlpEuler.Text = 'Ajuda';
            app.hlpEulerM = uibutton(app.uibuttongroup1, 'push');
            app.hlpEulerM.ButtonPushedFcn = createCallbackFcn(app, @hlpEulerM_Callback, true);
            app.hlpEulerM.Tag = 'hlpEulerM';
            app.hlpEulerM.Text = 'Ajuda';
            app.hlpRK2 = uibutton(app.uibuttongroup1, 'push');
            app.hlpRK2.ButtonPushedFcn = createCallbackFcn(app, @hlpRK2_Callback, true);
            app.hlpRK2.Tag = 'hlpRK2';
            app.hlpRK2.Text = 'Ajuda';
            app.hlpRK3 = uibutton(app.uibuttongroup1, 'push');
            app.hlpRK3.ButtonPushedFcn = createCallbackFcn(app, @hlpRK3_Callback, true);
            app.hlpRK3.Tag = 'hlpRK3';
            app.hlpRK3.Text = 'Ajuda';
            app.hlpRK4 = uibutton(app.uibuttongroup1, 'push');
            app.hlpRK4.ButtonPushedFcn = createCallbackFcn(app, @hlpRK4_Callback, true);
            app.hlpRK4.Tag = 'hlpRK4';
            app.hlpRK4.Text = 'Ajuda';
            app.hlpODE45 = uibutton(app.uibuttongroup1, 'push');
            app.hlpODE45.ButtonPushedFcn = createCallbackFcn(app, @hlpODE45_Callback, true);
            app.hlpODE45.Tag = 'hlpODE45';
            app.hlpODE45.Text = 'Ajuda';
            app.hlpODE113 = uibutton(app.uibuttongroup1, 'push');
            app.hlpODE113.ButtonPushedFcn = createCallbackFcn(app, @hlpODE113_Callback, true);
            app.hlpODE113.Tag = 'hlpODE113';
            app.hlpODE113.Text = 'Ajuda';
            app.hlpAdams = uibutton(app.uibuttongroup1, 'push');
            app.hlpAdams.ButtonPushedFcn = createCallbackFcn(app, @hlpAdams_Callback, true);
            app.hlpAdams.Tag = 'hlpAdams';
            app.hlpAdams.Text = 'Ajuda';
            app.cbEuler = uicheckbox(app.uibuttongroup1);
            app.cbEuler.Tag = 'cbEuler';
            app.cbEuler.Text = 'Euler';
            app.cbEulerM = uicheckbox(app.uibuttongroup1);
            app.cbEulerM.Tag = 'cbEulerM';
            app.cbEulerM.Text = 'Euler Melhorado';
            app.cbRK2 = uicheckbox(app.uibuttongroup1);
            app.cbRK2.Tag = 'cbRK2';
            app.cbRK2.Text = 'RK2';
            app.cbRK3 = uicheckbox(app.uibuttongroup1);
            app.cbRK3.Tag = 'cbRK3';
            app.cbRK3.Text = 'RK3';
            app.cbRK4 = uicheckbox(app.uibuttongroup1);
            app.cbRK4.Tag = 'cbRK4';
            app.cbRK4.Text = 'RK4';
            app.cbODE45 = uicheckbox(app.uibuttongroup1);
            app.cbODE45.Tag = 'cbODE45';
            app.cbODE45.Text = 'ODE45';
            app.cbODE113 = uicheckbox(app.uibuttongroup1);
            app.cbODE113.Tag = 'cbODE113';
            app.cbODE113.Text = 'ODE113';
            app.cbAdams = uicheckbox(app.uibuttongroup1);
            app.cbAdams.Tag = 'cbAdams';
            app.cbAdams.Text = 'Adams-Bashforth 4';
            app.btnAtualizar = uibutton(app.figIntGrafica, 'push');
            app.btnAtualizar.ButtonPushedFcn = createCallbackFcn(app, @btnAtualizar_Callback, true);
            app.btnAtualizar.Tag = 'btnAtualizar';
            app.btnAtualizar.Text = 'Atualizar';
            app.btnReset = uibutton(app.figIntGrafica, 'push');
            app.btnReset.ButtonPushedFcn = createCallbackFcn(app, @btnReset_Callback, true);
            app.btnReset.Tag = 'btnReset';
            app.btnReset.Text = 'Reset';
            app.objTable = uitable(app.figIntGrafica);
            app.objTable.ColumnName = {'t'; 'Exata'};
            app.objTable.RowName = '';
            app.objTable.ColumnEditable = [false false];
            app.objTable.Tag = 'objTable';
            app.uipanel2 = uipanel(app.figIntGrafica);
            app.uipanel2.Title = 'Controlo do Gráfico';
            app.uipanel2.Tag = 'uipanel2';
            app.cbGuide = uicheckbox(app.uipanel2);
            app.cbGuide.ValueChangedFcn = createCallbackFcn(app, @cbGuide_Callback, true);
            app.cbGuide.Tag = 'cbGuide';
            app.cbGuide.Text = 'Grelha';
            app.btnPrint = uibutton(app.figIntGrafica, 'push');
            app.btnPrint.ButtonPushedFcn = createCallbackFcn(app, @btnPrint_Callback, true);
            app.btnPrint.Tag = 'btnPrint';
            app.btnPrint.Text = 'Guardar Gráfico';
            % Show the figure after all components are created
            app.figIntGrafica.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceGrafica_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figIntGrafica)

            % Execute the startup function
            runStartupFcn(app, @(app)InterfaceGrafica_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figIntGrafica)
        end
    end
end