classdef menu_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figMenu     matlab.ui.Figure
        ctrlMenu    matlab.ui.container.Menu
        ctrlSair    matlab.ui.container.Menu
        txtTitulo   matlab.ui.control.Label
        btnGrafica  matlab.ui.control.Button
        btnTexto    matlab.ui.control.Button
        btnAutores  matlab.ui.control.Button
        btnExit     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function menu_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to menu (see VARARGIN)
            
            % Choose default command line output for menu
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: btnAutores
        function btnAutores_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btnAutores (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            delete(handles.figMenu);
            run Autor_App.mlapp;
        end

        % Button pushed function: btnExit
        function btnExit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btnExit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SN=questdlg('Quer mesmo sair?','Sair','Sim','Não','Sim');
            if strcmp(SN,'Não')
                return;
            end
            delete(handles.figMenu);
        end

        % Button pushed function: btnGrafica
        function btnGrafica_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btnGrafica (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            delete(handles.figMenu);
            run InterfaceGrafica_App.mlapp;
        end

        % Button pushed function: btnTexto
        function btnTexto_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btnTexto (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            delete(handles.figMenu);
            run InterfaceMenu.m;
        end

        % Menu selected function: ctrlSair
        function ctrlSair_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ctrlSair (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SN=questdlg('Quer mesmo sair?','Sair','Sim','Não','Sim');
            if strcmp(SN,'Não')
                return;
            end
            delete(handles.figMenu)
        end

        % Close request function: figMenu
        function figMenu_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            SN=questdlg('Quer mesmo sair?','Sair','Sim','Não','Sim');
            if strcmp(SN,'Não')
                return;
            end
            delete(hObject);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figMenu = uifigure('Visible', 'off');
            app.figMenu.Name = 'menu';
            app.figMenu.CloseRequestFcn = createCallbackFcn(app, @figMenu_CloseRequestFcn, true);
            app.figMenu.Tag = 'figMenu';
            app.ctrlMenu = uimenu(app.figMenu);
            app.ctrlMenu.Text = 'Menu';
            app.ctrlMenu.Tag = 'ctrlMenu';
            app.ctrlSair = uimenu(app.ctrlMenu);
            app.ctrlSair.MenuSelectedFcn = createCallbackFcn(app, @ctrlSair_Callback, true);
            app.ctrlSair.Text = 'Sair';
            app.ctrlSair.Tag = 'ctrlSair';
            app.txtTitulo = uilabel(app.figMenu);
            app.txtTitulo.Tag = 'txtTitulo';
            app.txtTitulo.Text = {'Métodos Núméricos para resolução de Equações Diferenciais Ordinárias e Problemas de Valor Inicial'; ''};
            app.btnGrafica = uibutton(app.figMenu, 'push');
            app.btnGrafica.ButtonPushedFcn = createCallbackFcn(app, @btnGrafica_Callback, true);
            app.btnGrafica.Tag = 'btnGrafica';
            app.btnGrafica.Text = 'Interface Gráfica para resolução de EDO/PVI';
            app.btnTexto = uibutton(app.figMenu, 'push');
            app.btnTexto.ButtonPushedFcn = createCallbackFcn(app, @btnTexto_Callback, true);
            app.btnTexto.Tag = 'btnTexto';
            app.btnTexto.Text = 'Interface de Texto para resolução de EDO/PVI';
            app.btnAutores = uibutton(app.figMenu, 'push');
            app.btnAutores.ButtonPushedFcn = createCallbackFcn(app, @btnAutores_Callback, true);
            app.btnAutores.Tag = 'btnAutores';
            app.btnAutores.Text = 'Autores';
            app.btnExit = uibutton(app.figMenu, 'push');
            app.btnExit.ButtonPushedFcn = createCallbackFcn(app, @btnExit_Callback, true);
            app.btnExit.Tag = 'btnExit';
            app.btnExit.Text = 'Sair';
            % Show the figure after all components are created
            app.figMenu.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figMenu)

            % Execute the startup function
            runStartupFcn(app, @(app)menu_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figMenu)
        end
    end
end