classdef At02_MNSED_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UiControl         matlab.ui.Figure
        Untitled_1        matlab.ui.container.Menu
        ctrlAutores       matlab.ui.container.Menu
        ctrlSair          matlab.ui.container.Menu
        Untitled_3        matlab.ui.container.Menu
        probPenduloDoc    matlab.ui.container.Menu
        ctrlExercicios    matlab.ui.container.Menu
        probBatimentoDoc  matlab.ui.container.Menu
        ctrlRelatorio     matlab.ui.container.Menu
        Untitled_2        matlab.ui.container.Menu
        probPendulo       matlab.ui.container.Menu
        probMMSA          matlab.ui.container.Menu
        probMMCA          matlab.ui.container.Menu
        probBatimento     matlab.ui.container.Menu
        axes1             matlab.ui.control.UIAxes
        uipanel1          matlab.ui.container.Panel
        menuSelect        matlab.ui.control.DropDown
        text2             matlab.ui.control.Label
        uipanel2          matlab.ui.container.Panel
        StrG              matlab.ui.control.EditField
        text3             matlab.ui.control.Label
        StrF              matlab.ui.control.EditField
        text4             matlab.ui.control.Label
        edtA              matlab.ui.control.EditField
        text5             matlab.ui.control.Label
        text6             matlab.ui.control.Label
        edtB              matlab.ui.control.EditField
        sliderH           matlab.ui.control.Slider
        text7             matlab.ui.control.Label
        edtN              matlab.ui.control.EditField
        uipanel3          matlab.ui.container.Panel
        edtU              matlab.ui.control.EditField
        txtU              matlab.ui.control.Label
        edtV              matlab.ui.control.EditField
        txtV              matlab.ui.control.Label
        uibuttongroup1    matlab.ui.container.ButtonGroup
        helpEuler         matlab.ui.control.Button
        helpEulerM        matlab.ui.control.Button
        helpRK2           matlab.ui.control.Button
        helpRK4           matlab.ui.control.Button
        cbEulerM          matlab.ui.control.CheckBox
        cbRK2             matlab.ui.control.CheckBox
        cbRK4             matlab.ui.control.CheckBox
        pbAtualuzar       matlab.ui.control.Button
        pbReset           matlab.ui.control.Button
        cbEuler           matlab.ui.control.CheckBox
        hlpRK3            matlab.ui.control.Button
        cbRK3             matlab.ui.control.CheckBox
        table             matlab.ui.control.Table
        cbGrelha          matlab.ui.control.CheckBox
    end

    
    % Properties for app components created in addRuntimeConfigurations
    properties (Access = public)
        uitoolbar1    matlab.ui.container.Toolbar
        uitoggletool1 matlab.ui.container.toolbar.ToggleTool
        uitoggletool2 matlab.ui.container.toolbar.ToggleTool
        uitoggletool3 matlab.ui.container.toolbar.ToggleTool
        uitoggletool4 matlab.ui.container.toolbar.ToggleTool
        uipushtool1   matlab.ui.container.toolbar.PushTool
        uipushtool2   matlab.ui.container.toolbar.PushTool
    end
    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('At02_MNSED_App.mat');
            
            % Create uitoolbar1
            app.uitoolbar1 = uitoolbar(app.UiControl);
            app.uitoolbar1.Tag = 'uitoolbar1';
            
            % Create uitoggletool1
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.ClickedCallback = 'putdowntext(''zoomin'',gcbo)';
            app.uitoggletool1.CData = componentData.uitoggletool1.CData;
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Zoom In';
            
            % Create uitoggletool2
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.ClickedCallback = 'putdowntext(''zoomout'',gcbo)';
            app.uitoggletool2.CData = componentData.uitoggletool2.CData;
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom Out';
            
            % Create uitoggletool3
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.ClickedCallback = 'putdowntext(''pan'',gcbo)';
            app.uitoggletool3.CData = componentData.uitoggletool3.CData;
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Pan';
            
            % Create uitoggletool4
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.ClickedCallback = 'putdowntext(''datatip'',gcbo)';
            app.uitoggletool4.CData = componentData.uitoggletool4.CData;
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Data Tips';
            
            % Create uipushtool1
            app.uipushtool1 = uipushtool(app.uitoolbar1);
            app.uipushtool1.ClickedCallback = createCallbackFcn(app, @uipushtool1_ClickedCallback, true);
            app.uipushtool1.BusyAction = 'cancel';
            app.uipushtool1.CData = componentData.uipushtool1.CData;
            app.uipushtool1.Interruptible = 'off';
            app.uipushtool1.Tag = 'uipushtool1';
            app.uipushtool1.Tooltip = 'Save Figure';
            
            % Create uipushtool2
            app.uipushtool2 = uipushtool(app.uitoolbar1);
            app.uipushtool2.ClickedCallback = 'printdlg(gcbf)';
            app.uipushtool2.BusyAction = 'cancel';
            app.uipushtool2.CData = componentData.uipushtool2.CData;
            app.uipushtool2.Interruptible = 'off';
            app.uipushtool2.Tag = 'uipushtool2';
            app.uipushtool2.Tooltip = 'Print Figure';
            
            % Set component properties that require runtime configuration
            app.table.BackgroundColor = [1 1 1];
            app.table.ColumnFormat = {[] []};
            app.table.Data = componentData.table.Data;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function At02_MNSED_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to At02_MNSED (see VARARGIN)
            
            % Choose default command line output for At02_MNSED
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            set(handles.cbEuler, 'Value', 1);
            if get(handles.menuSelect, 'Value') == 1
                set(handles.cbEuler, 'Enable', 'Inactive');
            end
        end

        % Value changed function: StrF
        function StrF_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to StrF (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of StrF as text
            %        str2double(get(hObject,'String')) returns contents of StrF as a double
            strF = get(hObject, 'String');
            f = @(t,u,v) eval(vectorize(strF));
            try
                syms t u v;
                fTest = f(t,u,v);
                set(hObject, 'BackgroundColor', 'white');
            catch me
                set(hObject, 'BackgroundColor', 'red');
                errordlg('ERRO! Introduza uma função em t, u e v', ...
                    'ERRO', 'modal');
            end
            
            handles.funcao = f;
            guidata(hObject, handles);
        end

        % Value changed function: StrG
        function StrG_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to StrG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of StrG as text
            %        str2double(get(hObject,'String')) returns contents of StrG as a double
            strG = get(hObject, 'String');
            g = @(t,u,v) eval(vectorize(strG));
            try
                syms t u v;
                fTest = g(t,u,v);
                set(hObject, 'BackgroundColor', 'white');
            catch me
                set(hObject, 'BackgroundColor', 'red');
                errordlg('ERRO! Introduza uma função em t, u e v', ...
                    'ERRO', 'modal');
            end
            
            handles.funcao = g;
            guidata(hObject, handles);
        end

        % Close request function: UiControl
        function UiControl_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to UiControl (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            SN = questdlg('Quer mesmo sair?', 'Sair', 'Sim', 'Nao', 'Sim');
            if strcmp(SN, 'Nao')
                return;
            end
            delete(hObject);
        end

        % Value changed function: cbGrelha
        function cbGrelha_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cbGrelha (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hint: get(hObject,'Value') returns toggle state of cbGrelha
            
            if get(hObject, 'Value') == 0
                grid off
            else
                grid on
            end
        end

        % Menu selected function: ctrlAutores
        function ctrlAutores_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ctrlAutores (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            run Autor_App.mlapp;
        end

        % Menu selected function: ctrlExercicios
        function ctrlExercicios_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ctrlExercicios (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            open 'Equações Diferenciais de ordem 2 _ Problemas de aplicação.pdf'
        end

        % Menu selected function: ctrlRelatorio
        function ctrlRelatorio_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ctrlRelatorio (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            open Relatório-da-Atividade-02.pdf;
        end

        % Menu selected function: ctrlSair
        function ctrlSair_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ctrlSair (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            SN = questdlg('Quer mesmo sair?', 'Sair', 'Sim', 'Nao', 'Sim');
            if strcmp(SN, 'Nao')
                return;
            end
            delete(handles.UiControl);
        end

        % Value changed function: edtA
        function edtA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtA (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edtA as text
            %        str2double(get(hObject,'String')) returns contents of edtA as a double
            a = str2num(get(hObject, 'string'));
            if (isscalar(a) && isreal(a) && a < str2num(get(handles.edtB, 'String')))
                set(hObject, 'BackgroundColor', 'white');
            else
                set(hObject, 'BackgroundColor', 'red');
                errordlg('Introduza um número real a, com a < b',...
                    'ERRO', 'modal');
            end
            
            handles.a = a;
            guidata(hObject, handles);
            
            set(handles.txtU, 'String', ['u (', num2str(a),') = ']);
            set(handles.txtV, 'String', ['v (', num2str(a),') = ']);
        end

        % Value changed function: edtB
        function edtB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edtB as text
            %        str2double(get(hObject,'String')) returns contents of edtB as a double
            b = str2num(get(hObject, 'String'));
            if (isscalar(b) && isreal(b) && b > str2num(get(handles.edtA, 'String')))
                set(hObject, 'BackgroundColor', 'white');
            else
                set(hObject, 'BackgroundColor', 'red');
                errordlg('Introduza um número real b, com b > a',...
                    'ERRO', 'modal');
            end
            
            handles.b = b;
            guidata(hObject, handles);
        end

        % Value changed function: edtN
        function edtN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edtN as text
            %        str2double(get(hObject,'String')) returns contents of edtN as a double
            val = str2double(get(hObject,'String'));
            
            if isnumeric(val) && length(val)==1 && ...
                    val >= get(handles.sliderH,'Min') && ...
                    val <= get(handles.sliderH,'Max')
                set(handles.sliderH,'Value',val);
                set(hObject, 'BackgroundColor', 'white');
            
            else
                errordlg({'     Introduziu um valor de N inválido';...
                    'N deve estar compreendido entre 1 e 100'},...
                    'ERRO', 'modal');
                set(hObject, 'BackgroundColor', 'red');
                uicontrol(hObject)
            end
        end

        % Value changed function: edtU
        function edtU_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtU (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edtU as text
            %        str2double(get(hObject,'String')) returns contents of edtU as a double
            u0 = str2num(get(hObject,'string'));
            a = get(handles.edtA,'string');
            if (isscalar(u0) && isreal(u0))
                set(hObject, 'BackgroundColor', 'white');
            else
                set(hObject, 'BackgroundColor', 'red');
                errordlg(['Introduza o valor de u(', a,')'],...
                    'ERRO', 'modal');
            end
            
            handles.u0 = u0;
            guidata(hObject, handles);
        end

        % Value changed function: edtV
        function edtV_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edtV (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edtV as text
            %        str2double(get(hObject,'String')) returns contents of edtV as a double
            v0 = str2num(get(hObject,'string'));
            a = get(handles.edtA,'string');
            if (isscalar(v0) && isreal(v0))
                set(hObject, 'BackgroundColor', 'white');
            else
                set(hObject, 'BackgroundColor', 'red');
                errordlg(['Introduza o valor de v(', a,')'],...
                    'ERRO', 'modal');
            end
            
            handles.v0 = v0;
            guidata(hObject, handles);
        end

        % Button pushed function: helpEulerM
        function helpEulerM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to helpEulerM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                          METODO DE EULER MELHORADO PARA SED';' ';...
                '[t, uEulerM, vEulerM] = EulerM(f, g, a, b, n, u0, y0) - Método numérico para a resolução de um PVI';...
                "u'= f(t,u,v), v'=g(t,u,v), t=[a, b], u(a)=u0 e v(a)=v0  "; ' ';...
            
                '   -> INPUT:';"f, g - funções do 2.º membro das Equações Diferenciais";...
                '[a, b] - extremos do intervalo da variável independente t';...
                'n - número de subintervalos ou iterações do método';...
                'u0, v0 - condições iniciais t=a -> u=u0 e v=v0';' ';...
            
                '   -> OUTPUT: '; '[t,u,v] - vector das soluções aproximações e da discretização de t';...
                'u(i+1) = u(i)+h*f(t(i),u(i),v(i))';...
                'v(i+1) = v(i)+h*g(t(i),u(i),v(i)) , i =0,1,...,n-1';' ';...
            
                'O Método de Euler Melhorado, consiste em uma aproximação semelhante';...
                'ao primeiro método utilizado, o Método de Euler, onde para fazer a ';
                'aproximação devemos realizar todos os passos do método de Euler, ';
                'desta forma observamos que o método de Euler melhorado é uma ';
                'modificação do método de Euler.';' '; ' '},...
                'Método de Euler Melhorado');
        end

        % Button pushed function: helpEuler
        function helpEuler_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to helpEuler (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                                  MÉTODO DE EULER PARA SED';' ';...
                '[t, uEuler, vEuler] = Euler(f, g, a, b, n, u0, y0) - Método numérico para a resolução de um PVI';...
                "u'= f(t,u,v), v'=g(t,u,v), t=[a, b], u(a)=u0 e v(a)=v0  "; ' ';...
            
                '   -> INPUT:';"f, g - funções do 2.º membro das Equações Diferenciais";...
                '[a, b] - extremos do intervalo da variável independente t';...
                'n - número de subintervalos ou iterações do método';...
                'u0, v0 - condições iniciais t=a -> u=u0 e v=v0';' ';...
            
                '   -> OUTPUT: '; '[t,u,v] - vector das soluções aproximações e da discretização de t';...
                'u(i+1) = u(i)+h*f(t(i),u(i),v(i))';...
                'v(i+1) = v(i)+h*g(t(i),u(i),v(i)) , i =0,1,...,n-1';' ';...
            
                'De acordo com Boyce e Diprima os trabalhos iniciais em métodos';['numéricos ',...
                'se iniciaram com Isaac Newton e Leibniz, mas foi com Lehonard'];['Euler ',...
                'no sec. XVIII que os métodos numéricos tiveram forma mais concreta,'];['Euler ',...
                'deduziu um processo iterativo que permitia determinar, de forma'];['aproximada, ',...
                'a solução de um problema de condição inicial num determinado'];'ponto. ';['É o tipo ',...
                'mais básico de método explícito para integração numérica '];'para equações diferenciais ordinárias.';' '; ' '},...
                'Método de Euler');
        end

        % Button pushed function: helpRK2
        function helpRK2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to helpRK2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                    MÉTODO DE RUNGE - KUTTA DE ORDEM 2 (RK2) para SED';' ';...
                '[t,uRK2,vRK2] = NRK2SED(f,g,a,b,n,u0,v0)';...
                "u'= f(t,u,v), v'=g(t,u,v), t=[a, b], u(a)=u0 e v(a)=v0";' ';...
            
                '-> INPUT:';"f, g - funções do 2.º membro das Equações Diferenciais";...
                "[a, b] - extremos do intervalo da variável independente t";...
                "n      - número de subintervalos ou interações do método";...
                "u0, v0 - condições iniciais t=a -> u=u0 e v=v0";' ';...
            
                '-> OUTPUT:';"[t,u,v] - vector das soluções aproximações e da discretização de t";...
                "k1_u        = h * f(t(i),u(i),v(i));";...
                "k1_v        = h * g(t(i),u(i),v(i));";...
                "k2_u        = h * f(t(i+1),   u(i)+k1_u,   v(i)+k1_v);";...
                "k2_v        = h * g(t(i+1),   u(i)+k1_u,   v(i)+k1_v);";...
                "u(i+1)=u(i)+(k1_u+k2_u)/2;";...
                "v(i+1)=v(i)+(k1_v+k2_v)/2;";' ';...
            
                'O método de Runge - Kutta de ordem 2 (RK2) é um dos métodos númericos';...
                'desenvolvidos pelos matemáticos alemães Carl Runge e Martin Kutta, por';...
                'volta de 1900. Os métodos desenvolvidos pelos dois matemáticos formam';...
                'uma vasta família de métodos numéricos diretos de passo simples para a';...
                'resolução de Equações Diferenciais Ordinárias / Problemas de Valor Inicial.';' '; ' '},...
                'Método de Runge-Kutta de Ordem 2');
        end

        % Button pushed function: helpRK4
        function helpRK4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to helpRK4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                    MÉTODO DE RUNGE - KUTTA DE ORDEM 4 (RK4) para SED';' ';...
                '[t,uRK4,vRK4] = NRK4SED(f,g,a,b,n,u0,v0)';...
                "u'= f(t,u,v), v'=g(t,u,v), t=[a, b], u(a)=u0 e v(a)=v0";' ';...
            
                '-> INPUT:';"f, g - funções do 2.º membro das Equações Diferenciais";...
                "[a, b] - extremos do intervalo da variável independente t";...
                "n      - número de subintervalos ou interações do método";...
                "u0, v0 - condições iniciais t=a -> u=u0 e v=v0";' ';...
            
                '-> OUTPUT:';"[t,u,v] - vector das soluções aproximações e da discretização de t";...
                "k1_u        = h * f(t(i),u(i),v(i));";...
                "k1_v        = h * g(t(i),u(i),v(i));";...
                "k2_u        = h * f(  t(i)+h/2,  u(i)+k1_u*(1/2),  v(i)+k1_v*(1/2));";...
                "k2_v        = h * g(  t(i)+h/2,  u(i)+k1_u*(1/2),  v(i)+k1_v*(1/2));";...
                "k3_u        = h * f(  t(i)+h/2,  u(i)+k2_u*(1/2),  v(i)+k2_v*(1/2));";...
                "k3_v        = h * g(  t(i)+h/2,  u(i)+k2_u*(1/2),  v(i)+k2_v*(1/2))";...
                "k4_u        = h * f(  t(i)+h/2,  u(i)+k3_u*(1/2),  v(i)+k3_v*(1/2));";...
                "k4_v        = h * g(  t(i)+h/2,  u(i)+k3_u*(1/2),  v(i)+k3_v*(1/2));";...
                "u(i+1)=u(i)+(k1_u+2*k2_u+2*k3_u+k4_u)/6";...
                "v(i+1)=v(i)+(k1_v+2*k2_v+2*k3_v+k4_v)/6;";' ';...
            
                'O método de Runge-Kutta de ordem 4 (RK4) é mais um dos muitos métodos ';...
                'numéricos iterativos, implícitos e explícitos, para a resolução numérica ';...
                '(aproximação) de soluções de Equações Diferenciais Ordinárias / Problemas de Valor Inicial. ';...
                'O RK4 é considerado o método mais preciso da família Runge - Kutta, sendo ';...
                'que quanto maior for o número de subintervalos, maior será a aproximação à solução exata.';...
                ' '; ' '},...
                'Método de Runge-Kutta de Ordem 4');
        end

        % Button pushed function: hlpRK3
        function hlpRK3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hlpRK3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h = msgbox({'                    MÉTODO DE RUNGE - KUTTA DE ORDEM 3 (RK3) para SED';' ';...
                '[t,uRK3,vRK3] = NRK3SED(f,g,a,b,n,u0,v0)';...
                "u'= f(t,u,v), v'=g(t,u,v), t=[a, b], u(a)=u0 e v(a)=v0";' ';...
            
                '-> INPUT:';"f, g - funções do 2.º membro das Equações Diferenciais";...
                "[a, b] - extremos do intervalo da variável independente t";...
                "n      - número de subintervalos ou interações do método";...
                "u0, v0 - condições iniciais t=a -> u=u0 e v=v0";' ';...
            
                '-> OUTPUT:';"[t,u,v] - vector das soluções aproximações e da discretização de t";...
                "k1_u        = h * f(t(i),u(i),v(i));";...
                "k1_v        = h * g(t(i),u(i),v(i));";...
                "k2_u        = h * f(  t(i)+h/2,  u(i)+k1_u*(1/2),  v(i)+k1_v*(1/2));";...
                "k2_v        = h * g(  t(i)+h/2,  u(i)+k1_u*(1/2),  v(i)+k1_v*(1/2));";...
                "k3_u        = h * f(  t(i)+h,  u(i)-k1_u+2*k2_u,  v(i)-k1_v+2*k2_v);";...
                "k3_v        = h * g(  t(i)+h,  u(i)-k1_u+2*k2_u,  v(i)-k1_v+2*k2_v);";...
                "u(i+1)=u(i)+(k1_u+4*k2_u+k3_u)/6;";...
                "v(i+1)=v(i)+(k1_v+4*k2_v+k3_v)/6;";' ';...
            
                'O método de Runge - Kutta de ordem 3 (RK3) é um dos métodos númericos';...
                'desenvolvidos pelos matemáticos alemães Carl Runge e Martin Kutta, por';...
                'volta de 1900. Os métodos desenvolvidos pelos dois matemáticos formam';...
                'uma vasta família de métodos numéricos diretos de passo simples para a';...
                'resolução de Equações Diferenciais Ordinárias / Problemas de Valor Inicial.';' '; ' '},...
                'Método de Runge-Kutta de Ordem 3');
        end

        % Value changed function: menuSelect
        function menuSelect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menuSelect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns menuSelect contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from menuSelect
            % Problema do Pendulo
            if get(hObject, 'Value') == 1
                set(handles.StrF, 'String', 'v');
                set(handles.StrG, 'String', '-sin(u)-0.3*v');
                set(handles.edtA, 'String', '0');
                set(handles.edtB, 'String', '15');
                set(handles.sliderH, 'Value', 100);
                set(handles.edtN, 'String', '100');
                set(handles.edtU, 'String', 'pi/2');
                set(handles.edtV, 'String', '0');
                set(handles.cbEuler, 'Value', 1);
                set(handles.cbEulerM, 'Value', 0);
                set(handles.cbRK2, 'Value', 0);
                set(handles.cbRK3, 'Value', 0);
                set(handles.cbRK4, 'Value', 0);
                set(handles.cbEuler, 'Enable', 'Inactive');
            end
            
            % Sistema mola-massa s/ amortecimento
            if get(hObject, 'Value') == 2
                set(handles.StrF, 'String', 'v');
                set(handles.StrG, 'String', '-16*u');
                set(handles.edtA, 'String', '0');
                set(handles.edtB, 'String', '2*pi');
                set(handles.sliderH, 'Value', 100);
                set(handles.edtN, 'String', '100');
                set(handles.edtU, 'String', '9');
                set(handles.edtV, 'String', '0');
                set(handles.cbEuler, 'Value', 0);
                set(handles.cbEulerM, 'Value', 0);
                set(handles.cbRK2, 'Value', 0);
                set(handles.cbRK3, 'Value', 0);
                set(handles.cbRK4, 'Value', 0);
                set(handles.cbEuler, 'Enable', 'on');
            end
            
            % Sistema mola-massa c/ amortecimento
            if get(hObject, 'Value') == 3
                set(handles.StrF, 'String', 'v');
                set(handles.StrG, 'String', '-10*v-25*u');
                set(handles.edtA, 'String', '0');
                set(handles.edtB, 'String', '2*pi');
                set(handles.sliderH, 'Value', 100);
                set(handles.edtN, 'String', '100');
                set(handles.edtU, 'String', '0');
                set(handles.edtV, 'String', '-4');
                set(handles.cbEuler, 'Value', 0);
                set(handles.cbEulerM, 'Value', 0);
                set(handles.cbRK2, 'Value', 0);
                set(handles.cbRK3, 'Value', 0);
                set(handles.cbRK4, 'Value', 0);
                set(handles.cbEuler, 'Enable', 'on');
            end
            
            % Vibracao Forcada nao Amortecida
            if get(hObject, 'Value') == 4
                set(handles.StrF, 'String', 'v');
                set(handles.StrG, 'String', '2.77778*sin(0.9*t)*sin(0.1*t)');
                set(handles.edtA, 'String', '0');
                set(handles.edtB, 'String', '80');
                set(handles.sliderH, 'Value', 800);
                set(handles.edtN, 'String', '800');
                set(handles.edtU, 'String', '0');
                set(handles.edtV, 'String', '0');
                set(handles.cbEuler, 'Value', 0);
                set(handles.cbEulerM, 'Value', 0);
                set(handles.cbRK2, 'Value', 0);
                set(handles.cbRK3, 'Value', 0);
                set(handles.cbRK4, 'Value', 0);
                set(handles.cbEuler, 'Enable', 'on');
            end
        end

        % Button pushed function: pbAtualuzar
        function pbAtualuzar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbAtualuzar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            strG = get(handles.StrG, 'String');
            g = @(t,u,v) eval(vectorize(strG));
            try
                syms t u v;
                fTest = g(t,u,v);
                set(handles.StrG, 'BackgroundColor', 'white');
            catch me
                set(handles.StrG, 'BackgroundColor', 'red');
                errordlg('ERRO! Introduza uma função em t, u e v', ...
                    'ERRO', 'modal');
            end
            
            strF = get(handles.StrF, 'String');
            f = @(t,u,v) eval(vectorize(strF));
            try
                syms t u v;
                fTest = f(t,u,v);
                set(handles.StrF, 'BackgroundColor', 'white');
            catch me
                set(handles.StrF, 'BackgroundColor', 'red');
                errordlg('ERRO! Introduza uma função em t, u e v', ...
                    'ERRO', 'modal');
            end
            
            a = str2num(get(handles.edtA, 'string'));
            if (isscalar(a) && isreal(a) && a < str2num(get(handles.edtB, 'String')))
                set(handles.edtA, 'BackgroundColor', 'white');
            else
                set(handles.edtA, 'BackgroundColor', 'red');
                errordlg('Introduza um número real a, com a < b',...
                    'ERRO', 'modal');
            end
            
            b = str2num(get(handles.edtB, 'String'));
            if (isscalar(b) && isreal(b) && b > str2num(get(handles.edtA, 'String')))
                set(handles.edtB, 'BackgroundColor', 'white');
            else
                set(handles.edtB, 'BackgroundColor', 'red');
                errordlg('Introduza um número real b, com b > a',...
                    'ERRO', 'modal');
            end
            
            u0 = str2num(get(handles.edtU,'string'));
            a = get(handles.edtA,'string');
            if (isscalar(u0) && isreal(u0))
                set(handles.edtU, 'BackgroundColor', 'white');
            else
                set(handles.edtU, 'BackgroundColor', 'red');
                errordlg(['Introduza o valor de u(', a,')'],...
                    'ERRO', 'modal');
            end
            
            v0 = str2num(get(handles.edtV,'string'));
            a = get(handles.edtA,'string');
            if (isscalar(v0) && isreal(v0))
                set(handles.edtV, 'BackgroundColor', 'white');
            else
                set(handles.edtV, 'BackgroundColor', 'red');
                errordlg(['Introduza o valor de v(', a,')'],...
                    'ERRO', 'modal');
            end
            
            n = get(handles.sliderH, 'Value');
            a = str2num(a);
            h = (b-a)/n;
            t = a:h:b;
            
            selecao = get(handles.menuSelect, 'Value');
            if selecao ~= 1
                % Calculo da solucao particular
                [sExata,~] = dsolve(['Du=', strF],['Dv=', strG],['u(', num2str(a), ')=' num2str(u0)'],...
                    ['v(', num2str(a), ')=', num2str(v0)]);
                fExata=@(t,u,v) eval(vectorize(char(sExata)));
                uExata=fExata(t);
            
                table_data = [transpose(t), transpose(uExata)];
                set(handles.table, 'Data', table_data, 'ColumnName', {'t', 'Exata'});
                plot(handles.axes1, t, uExata, 'r');
                legend('Exata');
                hold on;
            end
            
            % Problema do Pendulo
            if selecao == 1
                set(handles.cbEuler, 'Enable', 'Inactive');
                % EULER
                [~,uEuler,~] = NEulerSED(f, g, a, b, n, u0,v0);
                table_data = [transpose(t), transpose(uEuler)];
                set(handles.table, 'Data', table_data, 'ColumnName', {'t', 'Euler'});
                plot(handles.axes1, t, uEuler, 'b', 'DisplayName', 'Euler');
                legend('Euler');
                hold on
            
                % EULER MELHORADO
                if get(handles.cbEulerM, 'Value') == 1
                    [~,uEulerM,~]= NEulerMSED(f, g, a, b, n, u0,v0);
                    table_data(:,end+1) = transpose(uEulerM);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'Euler Melhorado';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uEulerM, 'g', 'DisplayName', 'Euler Melhorado');
                    legend('off');
                    legend('show');
                end
            
                % RK2
                if get(handles.cbRK2, 'Value') == 1
                    [~,uRK2,~]   =   NRK2SED(f, g, a, b, n, u0,v0);
                    table_data(:,end+1) = transpose(uRK2);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK2';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK2, 'm', 'DisplayName', 'RK2');
                    legend('off');
                    legend('show');
                end
            
                % RK3
                if get(handles.cbRK3, 'Value') == 1
                    [~,uRK3,~]   =   NRK3SED(f, g, a, b, n, u0,v0);
                    table_data(:,end+1) = transpose(uRK3);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK3';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK3, 'c', 'DisplayName', 'RK3');
                    legend('off');
                    legend('show');
                end
            
                % RK4
                if get(handles.cbRK4, 'Value') == 1
                    [~,uRK4,~]   =   NRK4SED(f, g, a, b, n, u0,v0);
                    table_data(:,end+1) = transpose(uRK4);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK4';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK4, 'k', 'DisplayName', 'RK4');
                    legend('off');
                    legend('show');
                end
            end
            
            % Sistema mola-massa s/ amortecimento
            if selecao == 2
                set(handles.cbEuler, 'Enable', 'on');
                % EULER
                if get(handles.cbEuler, 'Value') == 1
                    [~,uEuler,~] = NEulerSED(f, g, a, b, n, u0,v0);
                    erroEulerM = abs(uExata-uEuler);
                    table_data(:,end+1) = transpose(uEuler);
                    table_data(:,end+1) = transpose(erroEulerM);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'Euler';
                    table_name{end+1} = 'Erro - Euler';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uEuler, 'b', 'DisplayName', 'Euler');
                    legend('off');
                    legend('show');
                end
            
                % EULER MELHORADO
                if get(handles.cbEulerM, 'Value') == 1
                    [~,uEulerM,~]= NEulerMSED(f, g, a, b, n, u0,v0);
                    erroEulerM = abs(uExata-uEulerM);
                    table_data(:,end+1) = transpose(uEulerM);
                    table_data(:,end+1) = transpose(erroEulerM);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'Euler Melhorado';
                    table_name{end+1} = 'Erro - Euler Melhorado';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uEulerM, 'g', 'DisplayName', 'Euler Melhorado');
                    legend('off');
                    legend('show');
                end
            
                % RK2
                if get(handles.cbRK2, 'Value') == 1
                    [~,uRK2,~]   =   NRK2SED(f, g, a, b, n, u0,v0);
                    erroRK2 = abs(uExata-uRK2);
                    table_data(:,end+1) = transpose(uRK2);
                    table_data(:,end+1) = transpose(erroRK2);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK2';
                    table_name{end+1} = 'Erro - RK2';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK2, 'm', 'DisplayName', 'RK2');
                    legend('off');
                    legend('show');
                end
            
                % RK3
                if get(handles.cbRK3, 'Value') == 1
                    [~,uRK3,~]   =   NRK3SED(f, g, a, b, n, u0,v0);
                    erroRK3 = abs(uExata-uRK3);
                    table_data(:,end+1) = transpose(uRK3);
                    table_data(:,end+1) = transpose(erroRK3);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK3';
                    table_name{end+1} = 'Erro - RK3';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK3, 'c', 'DisplayName', 'RK3');
                    legend('off');
                    legend('show');
                end
            
                % RK4
                if get(handles.cbRK4, 'Value') == 1
                    [~,uRK4,~]   =   NRK4SED(f, g, a, b, n, u0,v0);
                    erroRK4 = abs(uExata-uRK4);
                    table_data(:,end+1) = transpose(uRK4);
                    table_data(:,end+1) = transpose(erroRK4);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK4';
                    table_name{end+1} = 'Erro - RK4';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK4, 'k', 'DisplayName', 'RK4');
                    legend('off');
                    legend('show');
                end
            end
            
            % Sistema mola-massa c/ amortecimento
            if selecao == 3
                set(handles.cbEuler, 'Enable', 'on');
                % EULER
                if get(handles.cbEuler, 'Value') == 1
                    [~,uEuler,~] = NEulerSED(f, g, a, b, n, u0,v0);
                    erroEulerM = abs(uExata-uEuler);
                    table_data(:,end+1) = transpose(uEuler);
                    table_data(:,end+1) = transpose(erroEulerM);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'Euler';
                    table_name{end+1} = 'Erro - Euler';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uEuler, 'b', 'DisplayName', 'Euler');
                    legend('off');
                    legend('show');
                end
            
                % EULER MELHORADO
                if get(handles.cbEulerM, 'Value') == 1
                    [~,uEulerM,~]= NEulerMSED(f, g, a, b, n, u0,v0);
                    erroEulerM = abs(uExata-uEulerM);
                    table_data(:,end+1) = transpose(uEulerM);
                    table_data(:,end+1) = transpose(erroEulerM);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'Euler Melhorado';
                    table_name{end+1} = 'Erro - Euler Melhorado';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uEulerM, 'g', 'DisplayName', 'Euler Melhorado');
                    legend('off');
                    legend('show');
                end
            
                % RK2
                if get(handles.cbRK2, 'Value') == 1
                    [~,uRK2,~]   =   NRK2SED(f, g, a, b, n, u0,v0);
                    erroRK2 = abs(uExata-uRK2);
                    table_data(:,end+1) = transpose(uRK2);
                    table_data(:,end+1) = transpose(erroRK2);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK2';
                    table_name{end+1} = 'Erro - RK2';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK2, 'm', 'DisplayName', 'RK2');
                    legend('off');
                    legend('show');
                end
            
                % RK3
                if get(handles.cbRK3, 'Value') == 1
                    [~,uRK3,~]   =   NRK3SED(f, g, a, b, n, u0,v0);
                    erroRK3 = abs(uExata-uRK3);
                    table_data(:,end+1) = transpose(uRK3);
                    table_data(:,end+1) = transpose(erroRK3);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK3';
                    table_name{end+1} = 'Erro - RK3';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK3, 'c', 'DisplayName', 'RK3');
                    legend('off');
                    legend('show');
                end
            
                % RK4
                if get(handles.cbRK4, 'Value') == 1
                    [~,uRK4,~]   =   NRK4SED(f, g, a, b, n, u0,v0);
                    erroRK4 = abs(uExata-uRK4);
                    table_data(:,end+1) = transpose(uRK4);
                    table_data(:,end+1) = transpose(erroRK4);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK4';
                    table_name{end+1} = 'Erro - RK4';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK4, 'k', 'DisplayName', 'RK4');
                    legend('off');
                    legend('show');
                end
            end
            
            % Vibracao Forcada nao Amortecida
            if selecao == 4
                set(handles.cbEuler, 'Enable', 'on');
                % EULER
                if get(handles.cbEuler, 'Value') == 1
                    [~,uEuler,~] = NEulerSED(f, g, a, b, n, u0,v0);
                    erroEulerM = abs(uExata-uEuler);
                    table_data(:,end+1) = transpose(uEuler);
                    table_data(:,end+1) = transpose(erroEulerM);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'Euler';
                    table_name{end+1} = 'Erro - Euler';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uEuler, 'b', 'DisplayName', 'Euler');
                    legend('off');
                    legend('show');
                end
            
                % EULER MELHORADO
                if get(handles.cbEulerM, 'Value') == 1
                    [~,uEulerM,~]= NEulerMSED(f, g, a, b, n, u0,v0);
                    erroEulerM = abs(uExata-uEulerM);
                    table_data(:,end+1) = transpose(uEulerM);
                    table_data(:,end+1) = transpose(erroEulerM);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'Euler Melhorado';
                    table_name{end+1} = 'Erro - Euler Melhorado';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uEulerM, 'g', 'DisplayName', 'Euler Melhorado');
                    legend('off');
                    legend('show');
                end
            
                % RK2
                if get(handles.cbRK2, 'Value') == 1
                    [~,uRK2,~]   =   NRK2SED(f, g, a, b, n, u0,v0);
                    erroRK2 = abs(uExata-uRK2);
                    table_data(:,end+1) = transpose(uRK2);
                    table_data(:,end+1) = transpose(erroRK2);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK2';
                    table_name{end+1} = 'Erro - RK2';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK2, 'm', 'DisplayName', 'RK2');
                    legend('off');
                    legend('show');
                end
            
                % RK3
                if get(handles.cbRK3, 'Value') == 1
                    [~,uRK3,~]   =   NRK3SED(f, g, a, b, n, u0,v0);
                    erroRK3 = abs(uExata-uRK3);
                    table_data(:,end+1) = transpose(uRK3);
                    table_data(:,end+1) = transpose(erroRK3);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK3';
                    table_name{end+1} = 'Erro - RK3';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK3, 'c', 'DisplayName', 'RK3');
                    legend('off');
                    legend('show');
                end
            
                % RK4
                if get(handles.cbRK4, 'Value') == 1
                    [~,uRK4,~]   =   NRK4SED(f, g, a, b, n, u0,v0);
                    erroRK4 = abs(uExata-uRK4);
                    table_data(:,end+1) = transpose(uRK4);
                    table_data(:,end+1) = transpose(erroRK4);
                    table_name = get(handles.table, 'ColumnName');
                    table_name{end+1} = 'RK4';
                    table_name{end+1} = 'Erro - RK4';
                    set(handles.table, 'Data', table_data);
                    set(handles.table, 'ColumnName', table_name);
                    plot(handles.axes1, t, uRK4, 'k', 'DisplayName', 'RK4');
                    legend('off');
                    legend('show');
                end
            end
            
            hold off
            
            if get(handles.cbGrelha, 'Value') == 1
                grid on;
            else
                grid off;
            end
        end

        % Button pushed function: pbReset
        function pbReset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbReset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cla(handles.axes1);
            
            set(handles.menuSelect, 'Value', 1);
            set(handles.StrF, 'String', 'v');
            set(handles.StrG, 'String', '-sin(u)-0.3*v');
            set(handles.edtA, 'String', '0');
            set(handles.edtB, 'String', '15');
            set(handles.sliderH, 'Value', 100);
            set(handles.edtN, 'String', '100');
            set(handles.edtU, 'String', 'pi/2');
            set(handles.edtV, 'String', '0');
            
            set(handles.table, 'Data', {});
            table_name = {'t'};
            set(handles.table, 'ColumnName', table_name);
            set(handles.cbEuler, 'Value', 1);
            set(handles.cbEulerM, 'Value', 0);
            set(handles.cbRK2, 'Value', 0);
            set(handles.cbRK3, 'Value', 0);
            set(handles.cbRK4, 'Value', 0);
            
            set(handles.menuSelect, 'Value', 1);
            set(handles.cbEuler, 'Enable', 'Inactive');
        end

        % Menu selected function: probBatimentoDoc
        function probBatimentoDoc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to probBatimentoDoc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            open 'Vibraçaõ Livre Forçada.pdf'
        end

        % Menu selected function: probBatimento
        function probBatimento_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to probBatimento (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.menuSelect, 'Value', 4);
            
            set(handles.StrF, 'String', 'v');
            set(handles.StrG, 'String', '2.77778*sin(0.9*t)*sin(0.1*t)');
            set(handles.edtA, 'String', '0');
            set(handles.edtB, 'String', '80');
            set(handles.sliderH, 'Value', 800);
            set(handles.edtN, 'String', '800');
            set(handles.edtU, 'String', '0');
            set(handles.edtV, 'String', '0');
            set(handles.cbEuler, 'Value', 0);
            set(handles.cbEulerM, 'Value', 0);
            set(handles.cbRK2, 'Value', 0);
            set(handles.cbRK3, 'Value', 0);
            set(handles.cbRK4, 'Value', 0);
            set(handles.cbEuler, 'Enable', 'on');
        end

        % Menu selected function: probMMCA
        function probMMCA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to probMMCA (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.menuSelect, 'Value', 3);
            
            set(handles.StrF, 'String', 'v');
            set(handles.StrG, 'String', '-10*v-25*u');
            set(handles.edtA, 'String', '0');
            set(handles.edtB, 'String', '2*pi');
            set(handles.sliderH, 'Value', 100);
            set(handles.edtN, 'String', '100');
            set(handles.edtU, 'String', '0');
            set(handles.edtV, 'String', '-4');
            set(handles.cbEuler, 'Value', 0);
            set(handles.cbEulerM, 'Value', 0);
            set(handles.cbRK2, 'Value', 0);
            set(handles.cbRK3, 'Value', 0);
            set(handles.cbRK4, 'Value', 0);
            set(handles.cbEuler, 'Enable', 'on');
        end

        % Menu selected function: probMMSA
        function probMMSA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to probMMSA (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.menuSelect, 'Value', 2);
            
            set(handles.StrF, 'String', 'v');
            set(handles.StrG, 'String', '-16*u');
            set(handles.edtA, 'String', '0');
            set(handles.edtB, 'String', '2*pi');
            set(handles.sliderH, 'Value', 100);
            set(handles.edtN, 'String', '100');
            set(handles.edtU, 'String', '9');
            set(handles.edtV, 'String', '0');
            set(handles.cbEuler, 'Value', 0);
            set(handles.cbEulerM, 'Value', 0);
            set(handles.cbRK2, 'Value', 0);
            set(handles.cbRK3, 'Value', 0);
            set(handles.cbRK4, 'Value', 0);
            set(handles.cbEuler, 'Enable', 'on');
        end

        % Menu selected function: probPenduloDoc
        function probPenduloDoc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to probPenduloDoc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            open 'Problema do Pendulo.pdf';
        end

        % Menu selected function: probPendulo
        function probPendulo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to probPendulo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.menuSelect, 'Value', 1);
            
            set(handles.StrF, 'String', 'v');
            set(handles.StrG, 'String', '-sin(u)-0.3*v');
            set(handles.edtA, 'String', '0');
            set(handles.edtB, 'String', '15');
            set(handles.sliderH, 'Value', 100);
            set(handles.edtN, 'String', '100');
            set(handles.edtU, 'String', 'pi/2');
            set(handles.edtV, 'String', '0');
            set(handles.cbEuler, 'Value', 1);
            set(handles.cbEulerM, 'Value', 0);
            set(handles.cbRK2, 'Value', 0);
            set(handles.cbRK3, 'Value', 0);
            set(handles.cbRK4, 'Value', 0);
            set(handles.cbEuler, 'Enable', 'Inactive');
        end

        % Value changed function: sliderH
        function sliderH_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sliderH (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            val = round(hObject.Value);
            hObject.Value = val;
            set(handles.edtN, 'String', num2str(get(hObject, 'Value')));
            handles.N = val;
            guidata(hObject, handles);
        end

        % Callback function
        function uipushtool1_ClickedCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uipushtool1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            exportgraphics(handles.objGraph, 'Plot.png');
            a = msgbox("Ficheiro guardado na pasta com o nome 'Plot.png'", 'modal');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UiControl = uifigure('Visible', 'off');
            app.UiControl.Name = 'At02_MNSED';
            app.UiControl.CloseRequestFcn = createCallbackFcn(app, @UiControl_CloseRequestFcn, true);
            app.UiControl.Tag = 'UiControl';
            app.Untitled_1 = uimenu(app.UiControl);
            app.Untitled_1.Text = 'Menu';
            app.Untitled_1.Tag = 'Untitled_1';
            app.ctrlAutores = uimenu(app.Untitled_1);
            app.ctrlAutores.MenuSelectedFcn = createCallbackFcn(app, @ctrlAutores_Callback, true);
            app.ctrlAutores.Text = 'Autores';
            app.ctrlAutores.Tag = 'ctrlAutores';
            app.ctrlSair = uimenu(app.Untitled_1);
            app.ctrlSair.MenuSelectedFcn = createCallbackFcn(app, @ctrlSair_Callback, true);
            app.ctrlSair.Accelerator = 'W';
            app.ctrlSair.Text = 'Sair';
            app.ctrlSair.Tag = 'ctrlSair';
            app.Untitled_3 = uimenu(app.UiControl);
            app.Untitled_3.Text = 'Documentação';
            app.Untitled_3.Tag = 'Untitled_3';
            app.probPenduloDoc = uimenu(app.Untitled_3);
            app.probPenduloDoc.MenuSelectedFcn = createCallbackFcn(app, @probPenduloDoc_Callback, true);
            app.probPenduloDoc.Text = 'Problema do Pêndulo';
            app.probPenduloDoc.Tag = 'probPenduloDoc';
            app.ctrlExercicios = uimenu(app.Untitled_3);
            app.ctrlExercicios.MenuSelectedFcn = createCallbackFcn(app, @ctrlExercicios_Callback, true);
            app.ctrlExercicios.Text = 'Problemas de Aplicação';
            app.ctrlExercicios.Tag = 'ctrlExercicios';
            app.probBatimentoDoc = uimenu(app.Untitled_3);
            app.probBatimentoDoc.MenuSelectedFcn = createCallbackFcn(app, @probBatimentoDoc_Callback, true);
            app.probBatimentoDoc.Text = 'Vibração Forçada não Amortecida (Batimento)';
            app.probBatimentoDoc.Tag = 'probBatimentoDoc';
            app.ctrlRelatorio = uimenu(app.Untitled_3);
            app.ctrlRelatorio.MenuSelectedFcn = createCallbackFcn(app, @ctrlRelatorio_Callback, true);
            app.ctrlRelatorio.Accelerator = 'I';
            app.ctrlRelatorio.Text = 'Relatorio da atividade';
            app.ctrlRelatorio.Tag = 'ctrlRelatorio';
            app.Untitled_2 = uimenu(app.UiControl);
            app.Untitled_2.Text = 'Problemas Predefinidos';
            app.Untitled_2.Tag = 'Untitled_2';
            app.probPendulo = uimenu(app.Untitled_2);
            app.probPendulo.MenuSelectedFcn = createCallbackFcn(app, @probPendulo_Callback, true);
            app.probPendulo.Text = 'Problema do Pêndulo';
            app.probPendulo.Tag = 'probPendulo';
            app.probMMSA = uimenu(app.Untitled_2);
            app.probMMSA.MenuSelectedFcn = createCallbackFcn(app, @probMMSA_Callback, true);
            app.probMMSA.Text = 'Sistema Mola-Massa S/ Amortecimento';
            app.probMMSA.Tag = 'probMMSA';
            app.probMMCA = uimenu(app.Untitled_2);
            app.probMMCA.MenuSelectedFcn = createCallbackFcn(app, @probMMCA_Callback, true);
            app.probMMCA.Text = 'Sistema Mola-Massa C/ Amortecimento';
            app.probMMCA.Tag = 'probMMCA';
            app.probBatimento = uimenu(app.Untitled_2);
            app.probBatimento.MenuSelectedFcn = createCallbackFcn(app, @probBatimento_Callback, true);
            app.probBatimento.Text = 'Vibração Forçada não Amortecida (Batimento)';
            app.probBatimento.Tag = 'probBatimento';
            app.axes1 = uiaxes(app.UiControl);
            app.axes1.Tag = 'axes1';
            app.uipanel1 = uipanel(app.UiControl);
            app.uipanel1.Tag = 'uipanel1';
            app.menuSelect = uidropdown(app.uipanel1);
            app.menuSelect.Items = {'Problema do Pêndulo', 'Sistema Mola-Massa S/ Amortecimento', 'Sistema Mola-Massa C/ Amortecimento', 'Vibração Forçada não Amortecida (Batimento)'};
            app.menuSelect.ValueChangedFcn = createCallbackFcn(app, @menuSelect_Callback, true);
            app.menuSelect.Tag = 'menuSelect';
            app.menuSelect.Value = 'Problema do Pêndulo';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Problemas Predefinidos';
            app.uipanel2 = uipanel(app.UiControl);
            app.uipanel2.Title = 'Dados do PVI';
            app.uipanel2.Tag = 'uipanel2';
            app.StrG = uieditfield(app.uipanel2, 'text');
            app.StrG.ValueChangedFcn = createCallbackFcn(app, @StrG_Callback, true);
            app.StrG.Tag = 'StrG';
            app.StrG.Value = '-sin(u)-0.3*v';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = ' g (t, u, v) =';
            app.StrF = uieditfield(app.uipanel2, 'text');
            app.StrF.ValueChangedFcn = createCallbackFcn(app, @StrF_Callback, true);
            app.StrF.Tag = 'StrF';
            app.StrF.Value = 'v';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'f (t, u, v) = ';
            app.edtA = uieditfield(app.uipanel2, 'text');
            app.edtA.ValueChangedFcn = createCallbackFcn(app, @edtA_Callback, true);
            app.edtA.Tag = 'edtA';
            app.edtA.Value = '0';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'a';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'b';
            app.edtB = uieditfield(app.uipanel2, 'text');
            app.edtB.ValueChangedFcn = createCallbackFcn(app, @edtB_Callback, true);
            app.edtB.Tag = 'edtB';
            app.edtB.Value = '15';
            app.sliderH = uislider(app.uipanel2);
            app.sliderH.Limits = [1 1000];
            app.sliderH.MajorTicks = [];
            app.sliderH.ValueChangedFcn = createCallbackFcn(app, @sliderH_Callback, true);
            app.sliderH.MinorTicks = [];
            app.sliderH.Tag = 'sliderH';
            app.sliderH.Value = 100;
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'N';
            app.edtN = uieditfield(app.uipanel2, 'text');
            app.edtN.ValueChangedFcn = createCallbackFcn(app, @edtN_Callback, true);
            app.edtN.Tag = 'edtN';
            app.edtN.Value = '100';
            app.uipanel3 = uipanel(app.uipanel2);
            app.uipanel3.Title = 'Condições Iniciais';
            app.uipanel3.Tag = 'uipanel3';
            app.edtU = uieditfield(app.uipanel3, 'text');
            app.edtU.ValueChangedFcn = createCallbackFcn(app, @edtU_Callback, true);
            app.edtU.Tag = 'edtU';
            app.edtU.Value = 'pi/2';
            app.txtU = uilabel(app.uipanel3);
            app.txtU.Tag = 'txtU';
            app.txtU.Text = 'u (0) =';
            app.edtV = uieditfield(app.uipanel3, 'text');
            app.edtV.ValueChangedFcn = createCallbackFcn(app, @edtV_Callback, true);
            app.edtV.Tag = 'edtV';
            app.edtV.Value = '0';
            app.txtV = uilabel(app.uipanel3);
            app.txtV.Tag = 'txtV';
            app.txtV.Text = 'v (0) =';
            app.uibuttongroup1 = uibuttongroup(app.uipanel2);
            app.uibuttongroup1.Title = 'Métodos Numéricos';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.helpEuler = uibutton(app.uibuttongroup1, 'push');
            app.helpEuler.ButtonPushedFcn = createCallbackFcn(app, @helpEuler_Callback, true);
            app.helpEuler.Tag = 'helpEuler';
            app.helpEuler.Text = 'Ajuda';
            app.helpEulerM = uibutton(app.uibuttongroup1, 'push');
            app.helpEulerM.ButtonPushedFcn = createCallbackFcn(app, @helpEulerM_Callback, true);
            app.helpEulerM.Tag = 'helpEulerM';
            app.helpEulerM.Text = 'Ajuda';
            app.helpRK2 = uibutton(app.uibuttongroup1, 'push');
            app.helpRK2.ButtonPushedFcn = createCallbackFcn(app, @helpRK2_Callback, true);
            app.helpRK2.Tag = 'helpRK2';
            app.helpRK2.Text = 'Ajuda';
            app.helpRK4 = uibutton(app.uibuttongroup1, 'push');
            app.helpRK4.ButtonPushedFcn = createCallbackFcn(app, @helpRK4_Callback, true);
            app.helpRK4.Tag = 'helpRK4';
            app.helpRK4.Text = 'Ajuda';
            app.cbEulerM = uicheckbox(app.uibuttongroup1);
            app.cbEulerM.Tag = 'cbEulerM';
            app.cbEulerM.Text = 'Euler Melhorado';
            app.cbRK2 = uicheckbox(app.uibuttongroup1);
            app.cbRK2.Tag = 'cbRK2';
            app.cbRK2.Text = 'RK2';
            app.cbRK4 = uicheckbox(app.uibuttongroup1);
            app.cbRK4.Tag = 'cbRK4';
            app.cbRK4.Text = 'RK4';
            app.pbAtualuzar = uibutton(app.uibuttongroup1, 'push');
            app.pbAtualuzar.ButtonPushedFcn = createCallbackFcn(app, @pbAtualuzar_Callback, true);
            app.pbAtualuzar.Tag = 'pbAtualuzar';
            app.pbAtualuzar.Text = 'Atualizar';
            app.pbReset = uibutton(app.uibuttongroup1, 'push');
            app.pbReset.ButtonPushedFcn = createCallbackFcn(app, @pbReset_Callback, true);
            app.pbReset.Tag = 'pbReset';
            app.pbReset.Text = 'Reset';
            app.cbEuler = uicheckbox(app.uibuttongroup1);
            app.cbEuler.Tag = 'cbEuler';
            app.cbEuler.Enable = 'off';
            app.cbEuler.Text = 'Euler';
            app.cbEuler.Value = true;
            app.hlpRK3 = uibutton(app.uibuttongroup1, 'push');
            app.hlpRK3.ButtonPushedFcn = createCallbackFcn(app, @hlpRK3_Callback, true);
            app.hlpRK3.Tag = 'hlpRK3';
            app.hlpRK3.Text = 'Ajuda';
            app.cbRK3 = uicheckbox(app.uibuttongroup1);
            app.cbRK3.Tag = 'cbRK3';
            app.cbRK3.Text = 'RK3';
            app.table = uitable(app.UiControl);
            app.table.ColumnName = {'t'; 'Euler'};
            app.table.RowName = '';
            app.table.ColumnEditable = [false false];
            app.table.Tag = 'table';
            app.cbGrelha = uicheckbox(app.UiControl);
            app.cbGrelha.ValueChangedFcn = createCallbackFcn(app, @cbGrelha_Callback, true);
            app.cbGrelha.Tag = 'cbGrelha';
            app.cbGrelha.Text = 'Grelha';
            % Show the figure after all components are created
            app.UiControl.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = At02_MNSED_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UiControl)

            % Execute the startup function
            runStartupFcn(app, @(app)At02_MNSED_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UiControl)
        end
    end
end