classdef Autor_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        crltVoltar   matlab.ui.container.Menu
        axesArmenio  matlab.ui.control.UIAxes
        axesVasco    matlab.ui.control.UIAxes
        axesFilipe   matlab.ui.control.UIAxes
        axesRui      matlab.ui.control.UIAxes
        axesFatinha  matlab.ui.control.UIAxes
        text1        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        text8        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Autor_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Autor (see VARARGIN)
            
            % Choose default command line output for Autor
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % I = imread('ArmenioCorreia.jpg');
            % image(I);
            % axis image
            % axis off
            I1=imread('ArmenioCorreia.JPG','JPEG');
            imshow(I1,[],'Parent',handles.axesArmenio)
            
            I2=imread('VascoPereira.JPEG', 'JPEG');
            imshow(I2, [], 'Parent', handles.axesVasco);
            
            I3=imread('RuiFerreira.JPG', 'JPEG');
            imshow(I3, [], 'Parent', handles.axesRui);
            
            I4=imread('FilipeMorgado.JPG', 'JPEG');
            imshow(I4, [], 'Parent', handles.axesFilipe);
            
            I5=imread('FatimaRodrigues.JPG', 'JPEG');
            imshow(I5, [], 'Parent', handles.axesFatinha);
        end

        % Menu selected function: crltVoltar
        function crltVoltar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to crltVoltar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            delete(handles.figure1);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Autor';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.crltVoltar = uimenu(app.figure1);
            app.crltVoltar.MenuSelectedFcn = createCallbackFcn(app, @crltVoltar_Callback, true);
            app.crltVoltar.Accelerator = 'B';
            app.crltVoltar.Text = 'Voltar';
            app.crltVoltar.Tag = 'crltVoltar';
            app.axesArmenio = uiaxes(app.figure1);
            app.axesArmenio.Tag = 'axesArmenio';
            app.axesVasco = uiaxes(app.figure1);
            app.axesVasco.Tag = 'axesVasco';
            app.axesFilipe = uiaxes(app.figure1);
            app.axesFilipe.Tag = 'axesFilipe';
            app.axesRui = uiaxes(app.figure1);
            app.axesRui.Tag = 'axesRui';
            app.axesFatinha = uiaxes(app.figure1);
            app.axesFatinha.Tag = 'axesFatinha';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Arménio Correia';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Vasco Pereira';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Filipe Morgado';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Mª Fátima Rodrigues';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Rui Ferreira';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autor_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Autor_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end